classdef PrediksiBarang < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        PesanLabel      matlab.ui.control.Label
        Label           matlab.ui.control.Label
        JumlahProduksi  matlab.ui.control.EditField
        HargaLabel      matlab.ui.control.Label
        OutputLabel     matlab.ui.control.Label
        ResetButton     matlab.ui.control.Button
        PrediksiButton  matlab.ui.control.Button
        BahanBakuEF     matlab.ui.control.NumericEditField
        LuasLabel       matlab.ui.control.Label
        PersediaanEF    matlab.ui.control.NumericEditField
        JarakLabel      matlab.ui.control.Label
        PermintaanEF    matlab.ui.control.NumericEditField
        HargaLabel_3    matlab.ui.control.Label
        InputLabel      matlab.ui.control.Label
        PrediksiJumlahProduksiBarangLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PrediksiButton
        function PrediksiButtonPushed(app, event)
            % Ditambahkan
            fis = readfis('ArifPrediksiJumlahbarang');
            permintaan = app.PermintaanEF.Value;
            persediaan = app.PersediaanEF.Value;
            bahan = app.BahanBakuEF.Value;

            Keputusan = evalfis(fis, [permintaan persediaan bahan]);

            inputData = [permintaan persediaan bahan];
            disp(inputData);

            app.JumlahProduksi.Value = sprintf('%.0f', Keputusan);

            if Keputusan < 2800
                app.Label.Text = "Sedikit";
            elseif Keputusan <= 5575
                app.Label.Text = "Sedang";
            else
                app.Label.Text= "Banyak";
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Ditambahakan
            app.PersediaanEF.Value = 1;
            app.PermintaanEF.Value = 1;
            app.BahanBakuEF.Value = 1;
            app.JumlahProduksi.Value = '0';
        end

        % Value changed function: PermintaanEF
        function PermintaanEFValueChanged(app, event)
            value = app.PermintaanEF.Value;
            if value > 5400  
                app.PermintaanEF.Value = 1;
                app.PesanLabel.Text = "PESAN: PermintaanEF tidak boleh lebih dari 5400!";
            elseif value < 1124
                app.PermintaanEF.Value = 1;
                app.PesanLabel.Text = "PESAN: PermintaanEF tidak boleh kurang dari 1124!";
            end
        end

        % Value changed function: PersediaanEF
        function PersediaanEFValueChanged(app, event)
            value = app.PersediaanEF.Value;
            if value > 5325
                app.PersediaanEF.Value = 1;
                app.PesanLabel.Text = "PESAN: PersediaanEF tidak boleh lebih dari 5325!";
            elseif value < 1050
                app.PersediaanEF.Value = 1;
                app.PesanLabel.Text = "PESAN: PersediaanEF tidak boleh kurang dari 1050!";
            end
        end

        % Value changed function: BahanBakuEF
        function BahanBakuEFValueChanged(app, event)
            value = app.BahanBakuEF.Value;
            if value > 3750
                app.BahanBakuEF.Value = 1;
                app.PesanLabel.Text = "PESAN: BahanBakuEF tidak boleh lebih dari 3750!";
            elseif value < 765
                app.BahanBakuEF.Value = 0;
                app.PesanLabel.Text = "PESAN: BahanBakuEF tidak boleh kurang dari 765!";
            end
        end

        % Callback function
        function sdasasg(app, event)
            value = app.FasilitasEF.Value;
            
        end

        % Callback function
        function FasilitasEFValueChanged(app, event)
            value = app.FasilitasEF.Value;
            if value > 6
                app.FasilitasEF.Value = 1;
                app.PesanLabel.Text = "PESAN: fasilitas tidak boleh lebih dari 6!";
            elseif value < 0
                app.FasilitasEF.Value = 0;
                app.PesanLabel.Text = "PESAN: fasilitas tidak boleh kurang dari 0!";
            end
        end

        % Callback function
        function KeamananEFValueChanged(app, event)
            value = app.KeamananEF.Value;
            if value > 6
                app.KeamananEF.Value = 1;
                app.PesanLabel.Text = "PESAN: keamanan tidak boleh lebih dari 6!";
            elseif value < 0
                app.KeamananEF.Value = 0;
                app.PesanLabel.Text = "PESAN: keamanan tidak boleh kurang dari 0!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PrediksiJumlahProduksiBarangLabel = uilabel(app.GridLayout);
            app.PrediksiJumlahProduksiBarangLabel.Tooltip = {'Obi Agustina'; '201011400884'; '07TPLP013'};
            app.PrediksiJumlahProduksiBarangLabel.Text = 'Prediksi Jumlah Produksi Barang';
            app.InputLabel = uilabel(app.GridLayout);
            app.InputLabel.Text = 'Input';
            app.HargaLabel_3 = uilabel(app.GridLayout);
            app.HargaLabel_3.Text = 'Permintaan 1124 - 5400 KG';
            app.PermintaanEF = uieditfield(app.GridLayout, 'numeric');
            app.PermintaanEF.ValueChangedFcn = createCallbackFcn(app, @PermintaanEFValueChanged, true);
            app.PermintaanEF.Tooltip = {'Harga : [ 1-170 ]'};
            app.JarakLabel = uilabel(app.GridLayout);
            app.JarakLabel.Text = 'Persediaan 1050 - 5325 KG';
            app.PersediaanEF = uieditfield(app.GridLayout, 'numeric');
            app.PersediaanEF.ValueChangedFcn = createCallbackFcn(app, @PersediaanEFValueChanged, true);
            app.PersediaanEF.Tooltip = {'jarak: [ 1-117 ]'};
            app.LuasLabel = uilabel(app.GridLayout);
            app.LuasLabel.Text = 'Bahan Baku 765 - 3750 KG';
            app.BahanBakuEF = uieditfield(app.GridLayout, 'numeric');
            app.BahanBakuEF.ValueChangedFcn = createCallbackFcn(app, @BahanBakuEFValueChanged, true);
            app.BahanBakuEF.Tooltip = {'luas: [ 0-10 ]'};
            app.PrediksiButton = uibutton(app.GridLayout, 'push');
            app.PrediksiButton.ButtonPushedFcn = createCallbackFcn(app, @PrediksiButtonPushed, true);
            app.PrediksiButton.Tag = 'tombolHitung';
            app.PrediksiButton.Text = 'Prediksi';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputLabel = uilabel(app.GridLayout);
            app.OutputLabel.Text = 'Output';
            app.HargaLabel = uilabel(app.GridLayout);
            app.HargaLabel.Text = 'Jumlah Produksi';
            app.JumlahProduksi = uieditfield(app.GridLayout, 'text');
            app.JumlahProduksi.Editable = 'off';
            app.JumlahProduksi.Tooltip = {''};
            app.JumlahProduksi.Placeholder = '0';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Hasil : ';
            app.PesanLabel = uilabel(app.GridLayout);
            app.PesanLabel.Text = 'PESAN: Kosong';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrediksiBarang

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end